classdef comsol_WPT_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        X_output_2       matlab.ui.control.EditField
        XLab_2           matlab.ui.control.Label
        Y_output_2       matlab.ui.control.EditField
        YLabel_2         matlab.ui.control.Label
        XLab             matlab.ui.control.Label
        startButton      matlab.ui.control.StateButton
        Y_output         matlab.ui.control.EditField
        YLabel           matlab.ui.control.Label
        X_output         matlab.ui.control.EditField
        Unit             matlab.ui.control.DropDown
        Label_6          matlab.ui.control.Label
        Gap              matlab.ui.control.NumericEditField
        Label_5          matlab.ui.control.Label
        Y                matlab.ui.control.NumericEditField
        Label_4          matlab.ui.control.Label
        X                matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        d                matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        N                matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
        ContextMenu      matlab.ui.container.ContextMenu
        sing             matlab.ui.container.Menu
        sing_layer1      matlab.ui.container.Menu
        sing_layer2      matlab.ui.container.Menu
        double           matlab.ui.container.Menu
        double_layer1    matlab.ui.container.Menu
        double_layer2    matlab.ui.container.Menu
        double_layer2_1  matlab.ui.container.Menu
        double_layer2_2  matlab.ui.container.Menu
        ContextMenu2     matlab.ui.container.ContextMenu
        Menu_2           matlab.ui.container.Menu
        Menu_3           matlab.ui.container.Menu
    end


    properties (Access = private)
        coil_type = 'singl';
        unit_type = 'off';
        coil_layer = '1';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: startButton
        function startButtonValueChanged(app, event)
            % 初始化
            app.X_output_2.Value = '';
            app.Y_output_2.Value = '';
            n = app.N.Value;
            wire_D = app.d.Value;
            y_length = app.Y.Value;
            gap = app.Gap.Value;
            if strcmp(app.coil_type,'singl')
                x_length = app.X.Value;
            elseif strcmp(app.coil_type,'double')
                x_length = app.X.Value/2 - gap/2;
            end
            r = wire_D/2;
            unit = app.Unit.Value;
            ratio = y_length/x_length;

            if logical(mod(n ,1)) || n <= 0
                msgbox(" 匝數需為正整數 !","Error","error")
                return;
            elseif wire_D*x_length*y_length <= 0
                msgbox(" 線徑、線圈寬與線圈長須大於零 !","Error","error")
                return;
            elseif gap < 0
                msgbox(" 線間空隙於不得小於零 !","Error","error")
                return;
            end

            % 預先生成向量加速計算過程
            last_point = n*4 + 1;
            x_mid = zeros(1, last_point );
            y_mid = x_mid;

            if strcmp(app.coil_type,'double')
                x_mid_d = x_mid;
                y_mid_d = x_mid;
                x_outside_d = x_mid;
                x_inside_d = x_mid;
                y_outside_d = x_mid;
                y_inside_d = x_mid;
            end
            x_outside = x_mid;
            x_inside = x_mid;
            y_outside = x_mid;
            y_inside = x_mid;

            % 用來紀錄長度變化
            y_length_a = y_length;
            y_length_ad = y_length;
            x_length_a = x_length;
            y_length_b = y_length;
            y_length_bd = y_length;
            x_length_b = x_length;

            % 初始點
            if strcmp(app.coil_type,'singl')

                x_mid(1) =  - x_length/2 + r;

            elseif strcmp(app.coil_type,'double')

                if strcmp(app.coil_layer,'1')
                    x_mid(1) =  r + gap/2;
                    x_mid_d(1) = - app.X.Value/2 + r;
                    y_mid_d(1) = - y_length/2;

                elseif strcmp(app.coil_layer,'2-1')

                    x_mid(1) =  -gap/2 - x_length + r ;
                    app.coil_type = 'singl';

                elseif strcmp(app.coil_layer,'2-2')

                    x_mid(1) = - x_length + r + app.X.Value/2  ;
                    app.coil_type = 'singl';

                end
            end

            y_mid(1) =  - y_length/2;
            p = 2;
            for point1 = 1:n

                for point2 = 1:4  % 座標運算

                    switch point2

                        case 1
                            % 左上
                            % 第一輪1*r
                            % 第二輪3*r
                            % 第三輪後4*r
                            % 左上第二輪開始要考慮線間距離 gap
                            if point1 >= 3                      %三輪後
                                y_length_a = y_length_a - 4*r - 2*gap;
                                y_length_ad = y_length_ad - 4*r - 2*gap;
                            elseif point1 ==2                   %第二輪
                                y_length_a = y_length_a - 3*r - gap;
                                y_length_ad = y_length_bd - 2*r - gap;
                            else                                %第一輪
                                y_length_a = y_length_a - r;
                                y_length_ad = y_length_ad - r;
                            end
                            % 軸心計算
                            x_mid(p) = x_mid(p-1);
                            y_mid(p) = y_mid(p-1) + y_length_a;
                            if strcmp(app.coil_type,'double')
                                x_mid_d(p) = x_mid_d(p-1);
                                y_mid_d(p) = y_mid_d(p-1) + y_length_ad;
                                x_outside_d(p) = x_mid_d(p) - r; y_outside_d(p) = y_mid_d(p) + r;
                                x_inside_d(p) = x_mid_d(p) + r; y_inside_d(p) = y_mid_d(p) - r;
                            end
                            % Outside  x-r y+r
                            % inside   x+r y-r
                            x_outside(p) = x_mid(p) - r; y_outside(p) = y_mid(p) + r;
                            x_inside(p) = x_mid(p) + r; y_inside(p) = y_mid(p) - r;
                        case 2
                            % 右上
                            % 右上第二輪開始要考慮線間距離 gap
                            % 軸心計算
                            if point1 > 1
                                x_length_a =  x_length_a - 4*r - 2*gap;
                            else
                                x_length_a =  x_length_a - 2*r;
                            end

                            x_mid(p) = x_mid(p-1) + x_length_a;
                            y_mid(p) = y_mid(p-1);

                            % Outside  x+r y+r
                            % inside   x-r y-r
                            x_outside(p) = x_mid(p) + r; y_outside(p) = y_mid(p) + r;
                            x_inside(p) = x_mid(p) - r; y_inside(p) = y_mid(p) - r;

                            if strcmp(app.coil_type,'double')
                                x_mid_d(p) = x_mid_d(p-1) + x_length_a;
                                y_mid_d(p) = y_mid_d(p-1);
                                x_outside_d(p) = x_mid_d(p) + r; y_outside_d(p) = y_mid_d(p) + r;
                                x_inside_d(p) = x_mid_d(p) - r; y_inside_d(p) = y_mid_d(p) - r;
                            end

                        case 3
                            % 3 右下
                            if point1 > 1
                                y_length_b = y_length_b - 4*r - 2*gap;
                                y_length_bd = y_length_bd - 4*r - 2*gap;
                            else
                                y_length_b = y_length_b - 2*r;
                                y_length_bd = y_length_bd - 4*r - gap;
                            end

                            x_mid(p) = x_mid(p-1) ;
                            y_mid(p) = y_mid(p-1) - y_length_b;
                            if strcmp(app.coil_type,'double')
                                x_mid_d(p) = x_mid_d(p-1);
                                y_mid_d(p) = y_mid_d(p-1) - y_length_bd;
                                x_outside_d(p) = x_mid_d(p) + r; y_outside_d(p) = y_mid_d(p) - r;
                                x_inside_d(p) = x_mid_d(p) - r; y_inside_d(p) = y_mid_d(p) + r;
                            end
                            % Outside  x+r y-r
                            % inside   x-r y+r
                            x_outside(p) = x_mid(p) + r; y_outside(p) = y_mid(p) - r;
                            x_inside(p) = x_mid(p) - r; y_inside(p) = y_mid(p) + r;
                        case 4
                            % 4 左下
                            % 左下第一輪開始要考慮線間距離 gap
                            if point1 > 1
                                x_length_b = x_length_b - 4*r - 2*gap;
                            else
                                x_length_b = x_length_b - 4*r - gap;
                            end

                            x_mid(p) = x_mid(p-1) - x_length_b;
                            y_mid(p) = y_mid(p-1);

                            % Outside  x-r y-r
                            % inside   x+r y+r
                            x_outside(p) = x_mid(p) - r; y_outside(p) = y_mid(p) - r;
                            x_inside(p) = x_mid(p) + r; y_inside(p) = y_mid(p) + r;

                            if strcmp(app.coil_type,'double')
                                x_mid_d(p) = x_mid_d(p-1) - x_length_b;
                                y_mid_d(p) = y_mid_d(p-1);
                                x_outside_d(p) = x_mid_d(p) - r; y_outside_d(p) = y_mid_d(p) - r;
                                x_inside_d(p) = x_mid_d(p) + r; y_inside_d(p) = y_mid_d(p) + r;
                            end

                    end
                    p = p + 1;
                end

            end

            % 開頭與結尾座標處理
            if strcmp(app.coil_type,'singl')

                x_outside(1) = x_mid(1) - r;
                x_inside(1) = x_mid(1) + r;
                x_outside(last_point) = x_mid(last_point) +r;
                x_inside(last_point) = x_mid(last_point) +r;

                y_outside(1) = y_mid(1);
                y_inside(1) = y_mid(1);
                y_outside(last_point) = y_mid(last_point) - r;
                y_inside(last_point) = y_mid(last_point) + r;


                if strcmp(app.coil_layer,'2-1')
                    %% When the coil layer is 2-1
                    x_outside_2_1 = -x_outside;
                    x_inside_2_1 = -x_inside;
                    y_outside_2_1 = y_outside;
                    y_inside_2_1 = y_inside;

                    x_out_new = [ x_outside_2_1(4) ,x_outside_2_1(1) ];
                    x_in_new =  [ x_outside_2_1(4) ,x_inside_2_1(1) ];
                    y_out_new = [ y_outside_2_1(4)-gap-wire_D ,y_outside_2_1(4)-gap-wire_D ];
                    y_in_new =  [ y_outside_2_1(4)-gap ,y_outside_2_1(4)-gap ];

                    x_outside_2_1(last_point) = [];
                    x_inside_2_1(last_point) = [];
                    x_outside_2_1(1) = [];
                    x_inside_2_1(1) = [];
                    y_outside_2_1(last_point) = [];
                    y_inside_2_1(last_point) = [];
                    y_outside_2_1(last_point-1) = y_outside_2_1(last_point-1) + wire_D;
                    y_inside_2_1(last_point-1) = y_outside_2_1(last_point-1);
                    y_outside_2_1(1) = [];
                    y_inside_2_1(1) = [];

                    x_outside_2_1 = [x_out_new ,x_outside_2_1];
                    x_inside_2_1 =  [x_in_new ,x_inside_2_1];
                    y_outside_2_1 = [y_out_new ,y_outside_2_1];
                    y_inside_2_1 =  [y_in_new ,y_inside_2_1];
                    
                    % points displacement

                    mn = ceil(length(y_outside_2_1)/2);
                    mat = 1:mn;
                    for u = 3:mn
                        mat(u) = mat(u-2) + 4;
                    end
                    y_outside_2_1(mat) = y_outside_2_1(mat) + gap + wire_D;
                    y_inside_2_1(mat) = y_inside_2_1(mat) + gap + wire_D;

                    % When the coil layer is 1

                elseif strcmp(app.coil_layer,'1')

                    % When the coil layer is 2 or 2-2

                elseif strcmp(app.coil_layer,'2') || strcmp(app.coil_layer,'2-2')

                    x_outside(last_point) = x_outside(last_point) +...
                        (x_outside(last_point-1)-x_outside(last_point)) - 2*wire_D - gap;
                    x_inside(last_point) = x_outside(last_point);

                    % When the coil layer is 2-2

                    if x_length ~= app.X.Value

                        % 2-2-2
                        x_outside_2_2_2 = x_outside;
                        x_inside_2_2_2 = x_inside;
                        y_outside_2_2_2 = y_outside;
                        y_inside_2_2_2 = y_inside;

                        % point one creat

                        x_addstart_out_2_2_2 = x_outside_2_2_2(1) + wire_D + gap;
                        x_addstart_in_2_2_2 = x_inside_2_2_2(1) + gap;
                        
                        % last point creat

                        x_addla_out_2_2_2 = [x_outside_2_2_2(last_point-3) + gap + wire_D...
                            ,x_outside_2_2_2(last_point-3) + gap + wire_D];
                        x_addla_in_2_2_2 = [x_addla_out_2_2_2(1) + wire_D...
                            ,x_addla_out_2_2_2(1) + wire_D];

                        y_addla_out_2_2_2 =  y_outside_2_2_2(last_point) + 2*wire_D + gap;
                        y_addla_in_2_2_2 = y_addla_out_2_2_2;
                        
                        % point [1,2] delete
                        x_outside_2_2_2([1,2,last_point]) = [];
                        x_inside_2_2_2([1,2,last_point]) = [];
                        y_outside_2_2_2(1) = [];
                        y_inside_2_2_2(1) = [];

                        % points connect
                        x_outside_2_2_2 = [x_addstart_out_2_2_2 ,x_outside_2_2_2 , x_addla_out_2_2_2];
                        x_inside_2_2_2 = [x_addstart_in_2_2_2 ,x_inside_2_2_2 ,x_addla_in_2_2_2];
                        y_outside_2_2_2 = [y_outside_2_2_2 , y_addla_out_2_2_2];
                        y_inside_2_2_2 = [y_inside_2_2_2 ,y_addla_in_2_2_2];

                        % points displacement

                        mn = ceil(length(x_outside_2_2_2)/2);
                        mat = [1,4,5];
                        if mn > 3
                            for u = 2:mn-2
                            mat(u+2) = mat(u) + 4;
                            end
                        end
                        
                        x_outside_2_2_2(mat) = x_outside_2_2_2(mat) - gap - wire_D;
                        x_inside_2_2_2(mat) = x_inside_2_2_2(mat) - gap - wire_D;

                        % total points
                        x_inside_2_2_2 = fliplr(x_inside_2_2_2);
                        y_inside_2_2_2 = fliplr(y_inside_2_2_2);
                        x_out_2_2_2 = [x_outside_2_2_2,x_inside_2_2_2];
                        y_out_2_2_2 = [y_outside_2_2_2,y_inside_2_2_2];

                        % 2-2-1
                        x_add_out_2_2_1 = [x_outside(1) - gap - wire_D,x_outside(1)];
                        x_add_in_2_2_1 = [x_outside(1) - gap - wire_D,x_inside(1)];
                        y_add_out_2_2_1 = [y_outside(1)  - gap ,y_outside(1)- gap];
                        y_add_in_2_2_1 = [y_outside(1)- wire_D - gap ,y_outside(1)- wire_D - gap];
                        x_outside(1)=[];
                        x_inside(1)=[];
                        y_outside(1)=[];
                        y_inside(1)=[];

                        % 2-2-1
                        x_outside = [x_add_out_2_2_1 ,x_outside];
                        x_inside = [x_add_in_2_2_1 ,x_inside];
                        y_outside = [y_add_out_2_2_1 ,y_outside];
                        y_inside = [y_add_in_2_2_1 ,y_inside];

                    end

                end
                x_inside = fliplr(x_inside);
                y_inside = fliplr(y_inside);
                x_out = [x_outside,x_inside];
                y_out = [y_outside,y_inside];
                if strcmp(app.coil_layer,'2-1')

                    x_inside_2_1 = fliplr(x_inside_2_1);
                    y_inside_2_1 = fliplr(y_inside_2_1);

                    x_out_2 = [x_outside_2_1,x_inside_2_1] ;
                    y_out_2 = [y_outside_2_1,y_inside_2_1];


                elseif strcmp(app.coil_layer,'2') || strcmp(app.coil_layer,'2-2')
                    x_out = -x_out;
                else

                end
                % if coil are double D type ...
            elseif strcmp(app.coil_type,'double')

                x_outside(1) = x_mid(1) - r;
                x_inside(1) = x_mid(1) + r;
                x_outside(last_point) = x_mid(last_point) +r;
                x_inside(last_point) = x_mid(last_point) +r;

                y_outside(1) = y_mid(1) + 2*r;
                y_inside(1) = y_mid(1);
                y_outside(last_point) = y_mid(last_point) - r;
                y_inside(last_point) = y_mid(last_point) + r;

                x_inside = fliplr(x_inside);
                y_inside = fliplr(y_inside);

                %============================================================
                x_outside_d(1) = x_mid_d(1) - r;
                x_inside_d(1) = x_mid_d(1) + r;
                x_outside_d(last_point) = [];
                x_inside_d(last_point) = [];

                y_outside_d(1) = y_mid_d(1);
                y_inside_d(1) = y_mid_d(1) + 2*r;
                y_outside_d(last_point) = [];
                y_inside_d(last_point) = [];
                y_outside_d(last_point-1) = y_mid_d(last_point-1) + r;
                y_inside_d(last_point-1) = y_mid_d(last_point-1) + r;

                x_inside_d = fliplr(x_inside_d);
                y_inside_d = fliplr(y_inside_d);

                x_out = [x_outside_d,x_inside_d,x_outside,x_inside];
                y_out = [y_outside_d,y_inside_d,y_outside,y_inside];

            end


            % print
            x_out_str ="";
            y_out_str ="";
            if strcmp(app.unit_type,'off')

                if strcmp(app.coil_layer,'2-1')

                    x_out_str_2_1 ="";
                    y_out_str_2_1 ="";

                    for s = 1:length(x_out)

                        x_out_str = x_out_str + x_out(s) +" ";
                        y_out_str = y_out_str + y_out(s) +" ";
                        x_out_str_2_1 = x_out_str_2_1 + x_out_2(s) +" ";
                        y_out_str_2_1 = y_out_str_2_1 + y_out_2(s) +" ";

                    end

                    app.X_output_2.Value = x_out_str_2_1;
                    app.Y_output_2.Value = y_out_str_2_1;

                else

                    for s = 1:length(x_out)

                        x_out_str = x_out_str + x_out(s) +" ";
                        y_out_str = y_out_str + y_out(s) +" ";

                    end
                    if strcmp(app.coil_layer,'2-2')

                        x_out_str_2_2_2 ="";
                        y_out_str_2_2_2 ="";

                        for s = 1:length(x_out_2_2_2)

                            x_out_str_2_2_2 = x_out_str_2_2_2 + x_out_2_2_2(s) +" ";
                            y_out_str_2_2_2 = y_out_str_2_2_2 + y_out_2_2_2(s) +" ";

                        end
                        app.X_output_2.Value = x_out_str_2_2_2;
                        app.Y_output_2.Value = y_out_str_2_2_2;
                    end
                end

            else

                for s = 1:length(x_out)

                    x_out_str = x_out_str + x_out(s) +"["+app.Unit.Value+"] ";
                    y_out_str = y_out_str + y_out(s) +"["+app.Unit.Value+"] ";

                end

            end

            app.X_output.Value = x_out_str;
            app.Y_output.Value = y_out_str;

            fill(app.UIAxes,x_out,y_out,[0.5,0.5,0.5])

            if strcmp(app.coil_layer,'2-1')
                hold(app.UIAxes,"on");
                app.coil_type = 'double';
                fill(app.UIAxes,x_out_2,y_out_2,[0.5,0.5,0.5])
                hold(app.UIAxes,"off");
            elseif strcmp(app.coil_layer,'2-2')
                hold(app.UIAxes,"on");
                app.coil_type = 'double';
                fill(app.UIAxes,x_out_2_2_2,y_out_2_2_2,[0.5,0.5,0.5])
                hold(app.UIAxes,"off");
            end
            if ratio <= 2 || ratio >= (1/2)
                spacex = 3;
            elseif ratio <= 3 || ratio >= (1/3)
                spacex = 5;
            elseif ratio <= 5 || ratio >= (1/5)
                spacex = 10;
            elseif ratio <= 8 || ratio >= (1/8)
                spacex = 15;
            elseif ratio <= 10 || ratio >= (1/10)
                spacex = 20;
            end

            spacey = spacex / ratio;
            xlim(app.UIAxes,[-app.X.Value/2-spacex,app.X.Value/2+spacex])
            ylim(app.UIAxes,[-y_length/2-spacey,y_length/2+spacey])
            xlabel(app.UIAxes,'線圈X軸寬'+"("+unit+")")
            ylabel(app.UIAxes,'線圈Y軸長'+"("+unit+")")


        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            app.unit_type = 'on';
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            app.unit_type = 'off';
        end

        % Menu selected function: sing_layer1
        function sing_layer1MenuSelected(app, event)
            app.coil_type = 'singl';
            app.coil_layer = '1';
        end

        % Menu selected function: double_layer1
        function double_layer1MenuSelected(app, event)
            app.coil_type = 'double';
            app.coil_layer = '1';
        end

        % Menu selected function: double_layer2_1
        function double_layer2_1MenuSelected(app, event)
            app.coil_type = 'double';
            app.coil_layer = '2-1';
        end

        % Menu selected function: double_layer2_2
        function double_layer2_2MenuSelected(app, event)
            app.coil_type = 'double';
            app.coil_layer = '2-2';
        end

        % Menu selected function: sing_layer2
        function sing_layer2MenuSelected(app, event)
            app.coil_type = 'singl';
            app.coil_layer = '2';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '線圈XY平面參考圖')
            xlabel(app.UIAxes, '線圈X軸寬')
            ylabel(app.UIAxes, '線圈Y軸長')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '線圈匝數';
            app.N = uieditfield(app.UIFigure, 'numeric');
            app.N.Value = 5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '線徑';
            app.d = uieditfield(app.UIFigure, 'numeric');
            app.d.Value = 0.3;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '線圈寬';
            app.X = uieditfield(app.UIFigure, 'numeric');
            app.X.Value = 15;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '線圈長';
            app.Y = uieditfield(app.UIFigure, 'numeric');
            app.Y.Value = 15;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '線間空隙';
            app.Gap = uieditfield(app.UIFigure, 'numeric');
            app.Gap.Value = 0.5;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '單位';
            app.Unit = uidropdown(app.UIFigure);
            app.Unit.Items = {'mm', 'cm', 'm'};
            app.Unit.Value = 'mm';
            app.X_output = uieditfield(app.UIFigure, 'text');
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y1';
            app.Y_output = uieditfield(app.UIFigure, 'text');
            app.startButton = uibutton(app.UIFigure, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButtonValueChanged, true);
            app.startButton.Text = 'start';
            app.XLab = uilabel(app.UIFigure);
            app.XLab.Text = 'X1';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y2';
            app.Y_output_2 = uieditfield(app.UIFigure, 'text');
            app.XLab_2 = uilabel(app.UIFigure);
            app.XLab_2.Text = 'X2';
            app.X_output_2 = uieditfield(app.UIFigure, 'text');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.sing = uimenu(app.ContextMenu);
            app.sing.Text = '單線圈';
            app.sing_layer1 = uimenu(app.sing);
            app.sing_layer1.MenuSelectedFcn = createCallbackFcn(app, @sing_layer1MenuSelected, true);
            app.sing_layer1.Text = '第一層';
            app.sing_layer2 = uimenu(app.sing);
            app.sing_layer2.MenuSelectedFcn = createCallbackFcn(app, @sing_layer2MenuSelected, true);
            app.sing_layer2.Text = '第二層';
            app.double = uimenu(app.ContextMenu);
            app.double.Text = '雙D線圈';
            app.double_layer1 = uimenu(app.double);
            app.double_layer1.MenuSelectedFcn = createCallbackFcn(app, @double_layer1MenuSelected, true);
            app.double_layer1.Text = '單層';
            app.double_layer2 = uimenu(app.double);
            app.double_layer2.Text = '雙層';
            app.double_layer2_1 = uimenu(app.double_layer2);
            app.double_layer2_1.MenuSelectedFcn = createCallbackFcn(app, @double_layer2_1MenuSelected, true);
            app.double_layer2_1.Text = '第一層';
            app.double_layer2_2 = uimenu(app.double_layer2);
            app.double_layer2_2.MenuSelectedFcn = createCallbackFcn(app, @double_layer2_2MenuSelected, true);
            app.double_layer2_2.Text = '第二層';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '啟用單位';
            app.Menu_3 = uimenu(app.ContextMenu2);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '關閉單位';
            % Assign app.ContextMenu2
            app.startButton.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = comsol_WPT_V5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end